classdef RetoFeriaAppFinals33 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        MasaSlider          matlab.ui.control.Slider
        MasaSliderLabel     matlab.ui.control.Label
        ohmsSlider          matlab.ui.control.Slider
        ohmsSliderLabel     matlab.ui.control.Label
        VueltasSlider       matlab.ui.control.Slider
        VueltasSliderLabel  matlab.ui.control.Label
        RadioSlider         matlab.ui.control.Slider
        RadioSliderLabel    matlab.ui.control.Label
        RunButton           matlab.ui.control.Button
        CorrGraph           matlab.ui.control.UIAxes
        AcGraph             matlab.ui.control.UIAxes
        VelGraph            matlab.ui.control.UIAxes
        PosGraph            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RadioSlider
        function RadioSliderValueChanged(app, event)
            value = app.RadioSlider.Value;
            
        end

        % Value changed function: VueltasSlider
        function VueltasSliderValueChanged(app, event)
            value = app.VueltasSlider.Value;
            
        end

        % Value changed function: MasaSlider
        function MasaSliderValueChanged(app, event)
            value = app.MasaSlider.Value;
            
        end

        % Value changed function: ohmsSlider
        function ohmsSliderValueChanged(app, event)
            value = app.ohmsSlider.Value;
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            %DECLARACION
            
            miu = 4.*pi.*(10.^-7); %Miu
            I = 55; %Corriente
            A = 10; %Area
            M = 14560; %Momento Magnetico
            R = app.RadioSlider.Value; %Radio de la espira
            l = 10; %Longitud de espira
            g = 9.81; %Gravedad
            N = app.VueltasSlider.Value; %Numero de vueltas
            Ohm = app.ohmsSlider.Value; %Resistencia en Ohms
            m = app.MasaSlider.Value; %Masa en kilogramos
            
            vo = 0; %Valor inicial velocidad
            vz(1) = vo; %Vector velocidad
            zo = 5; %Z inicial
            zm(1) = zo; %Vector Z
            
            t(1) = 0; %Vector Tiempo
            dt = 0.05; %Salto en tiempo
            
            km = (9 * (miu^2) * (M^2) * (R^4) * (N^2)) / (4 * m * Ohm);

            %Calcular Peso
            w=m*(-g);
            %Vectores de acelerarion, corriente y fem
            acel(1) = 0;
            corr(1) = 0;
            fem(1) = 0;

            dr = N/l;

            %Contador
            conteo = 1; 

            while (zm(conteo)> -3) %Mientras zm actual no sea menor a 3
            
                B = (miu*I*(R^2))/(2*(R^2+zm(conteo)^2)^(3/2)); %Campo magnetico
                fuerzamag(conteo) = (6*miu*I*R^2*M*(zm(conteo)))/(4*((zm(conteo))^2+R^2)^(5/2)); %Calculo de fuerza magnetica
                F = fuerzamag(conteo)+w; %Fuerza
                acel(conteo+1) = F/m; %Aceleracion
                pause(0.01)
            
                t(conteo+1)=t(conteo)+dt; %Añadir al vector tiempo
                zm(conteo+1)=zm(conteo)+vz(conteo)*dt+0.5*acel(conteo+1)*dt*dt;%Calculo zm nueva
                vz(conteo+1)=(zm(conteo+1)-zm(conteo))/dt; %Calculo velocidad nueva
                fem(conteo+1)= fem(conteo)+(B*dr^2); %Calculo fem
                corr(conteo+1) = (km*zm(conteo)*vz(conteo))/((Ohm*Ohm+zm(conteo)^2)^2.5); %Calculo corriente
                
                conteo=conteo+1; %Contador
                %Destruimos dipolo
            end
            
            
            %Figuras graficas

            plot(app.PosGraph,t,zm)
            title(app.PosGraph,"Posicion vs tiempo")
            ylabel(app.PosGraph,"Posicion (m)")
            xlabel(app.PosGraph,"tiempo")

          
            plot(app.VelGraph,t,vz)
            title(app.VelGraph,"Velocidad vs tiempo")
            ylabel(app.VelGraph,"Velocidad (m/s)")
            xlabel(app.VelGraph,"tiempo")


            plot(app.AcGraph,t,acel)
            title(app.AcGraph,"Aceleracion vs tiempo")
            ylabel(app.AcGraph,"Aceleracion (m/s^2)")
            xlabel(app.AcGraph,"tiempo")


            plot(app.CorrGraph,t,corr)
            title(app.CorrGraph,"Corriente vs tiempo")
            ylabel(app.CorrGraph,"Corriente")
            xlabel(app.CorrGraph,"tiempo")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PosGraph = uiaxes(app.UIFigure);
            title(app.PosGraph, 'Posición vs Tiempo')
            xlabel(app.PosGraph, 'Tiempo')
            ylabel(app.PosGraph, 'Posición')
            zlabel(app.PosGraph, 'Z')
            app.VelGraph = uiaxes(app.UIFigure);
            title(app.VelGraph, 'Velocidad vs Tiempo')
            xlabel(app.VelGraph, 'Tiempo')
            ylabel(app.VelGraph, 'Velocidad')
            zlabel(app.VelGraph, 'Z')
            app.AcGraph = uiaxes(app.UIFigure);
            title(app.AcGraph, 'Aceleración vs Tiempo')
            xlabel(app.AcGraph, 'Tiempo')
            ylabel(app.AcGraph, 'Aceleración')
            zlabel(app.AcGraph, 'Z')
            app.CorrGraph = uiaxes(app.UIFigure);
            title(app.CorrGraph, 'Corriente vs Tiempo')
            xlabel(app.CorrGraph, 'Tiempo')
            ylabel(app.CorrGraph, 'Corriente')
            zlabel(app.CorrGraph, 'Z')
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.RadioSliderLabel = uilabel(app.UIFigure);
            app.RadioSliderLabel.Text = 'Radio';
            app.RadioSlider = uislider(app.UIFigure);
            app.RadioSlider.Limits = [0.5 5];
            app.RadioSlider.ValueChangedFcn = createCallbackFcn(app, @RadioSliderValueChanged, true);
            app.RadioSlider.Value = 2;
            app.VueltasSliderLabel = uilabel(app.UIFigure);
            app.VueltasSliderLabel.Text = 'Vueltas';
            app.VueltasSlider = uislider(app.UIFigure);
            app.VueltasSlider.Limits = [5 50];
            app.VueltasSlider.ValueChangedFcn = createCallbackFcn(app, @VueltasSliderValueChanged, true);
            app.VueltasSlider.Value = 20;
            app.ohmsSliderLabel = uilabel(app.UIFigure);
            app.ohmsSliderLabel.Text = 'ohms';
            app.ohmsSlider = uislider(app.UIFigure);
            app.ohmsSlider.Limits = [5 20];
            app.ohmsSlider.ValueChangedFcn = createCallbackFcn(app, @ohmsSliderValueChanged, true);
            app.ohmsSlider.Value = 10;
            app.MasaSliderLabel = uilabel(app.UIFigure);
            app.MasaSliderLabel.Text = 'Masa';
            app.MasaSlider = uislider(app.UIFigure);
            app.MasaSlider.Limits = [0.002 0.01];
            app.MasaSlider.ValueChangedFcn = createCallbackFcn(app, @MasaSliderValueChanged, true);
            app.MasaSlider.Value = 0.005;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RetoFeriaAppFinals33

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end